classdef COVID19_Matlab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StatisticsTab                   matlab.ui.container.Tab
        place                           matlab.ui.container.ButtonGroup
        all                             matlab.ui.control.ToggleButton
        specific                        matlab.ui.control.ToggleButton
        TabGroup_2                      matlab.ui.container.TabGroup
        InfectionsTab                   matlab.ui.container.Tab
        ax_infections                   matlab.ui.control.UIAxes
        DeathsTab                       matlab.ui.container.Tab
        ax_deaths                       matlab.ui.control.UIAxes
        GrowthRatebyCountryTab          matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        gr_table                        matlab.ui.control.Table
        gr_hist                         matlab.ui.control.UIAxes
        PlotTypesButtonGroup            matlab.ui.container.ButtonGroup
        lin                             matlab.ui.control.ToggleButton
        log                             matlab.ui.control.ToggleButton
        add_trend_lines                 matlab.ui.control.CheckBox
        daystocomputetrendLabel         matlab.ui.control.Label
        SubsetofCountriesListBoxLabel   matlab.ui.control.Label
        countries                       matlab.ui.control.ListBox
        UseLabel                        matlab.ui.control.Label
        days_for_trend                  matlab.ui.control.NumericEditField
        mostrecentdaystovalidatepredictionsLabel  matlab.ui.control.Label
        HoldoutLabel                    matlab.ui.control.Label
        days_for_pred                   matlab.ui.control.NumericEditField
        MapsTab                         matlab.ui.container.Tab
        map                             matlab.ui.control.UIAxes
        ShowMapButton                   matlab.ui.control.Button
        TimeSliderLabel                 matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        TimerelativetotodaydaysLabel    matlab.ui.control.Label
        GenerateMovieButton             matlab.ui.control.Button
        SIRSimulationsTab               matlab.ui.container.Tab
        SocialDistancingPreSymptomSliderLabel  matlab.ui.control.Label
        d_pre                           matlab.ui.control.Slider
        AvailableHospitalBedsLabel      matlab.ui.control.Label
        M_Hosp                          matlab.ui.control.Slider
        TimetoVisibleSymptomsdaysSliderLabel  matlab.ui.control.Label
        T_vis                           matlab.ui.control.Slider
        ssa_results                     matlab.ui.control.UIAxes
        InitialSusceptibleEditFieldLabel  matlab.ui.control.Label
        S_0                             matlab.ui.control.NumericEditField
        InitialInfectedUndetectedEditFieldLabel  matlab.ui.control.Label
        IU_0                            matlab.ui.control.NumericEditField
        InitialInfectedDetectedEditFieldLabel  matlab.ui.control.Label
        ID_0                            matlab.ui.control.NumericEditField
        RunSimmulationButton            matlab.ui.control.Button
        SocialDistancingPostSymptomSliderLabel  matlab.ui.control.Label
        d_post                          matlab.ui.control.Slider
        MildNoSymptomsLabel             matlab.ui.control.Label
        TimetoRecoverydaysLabel_2       matlab.ui.control.Label
        t_r_nohosp                      matlab.ui.control.Slider
        SevereSymptomsNoHospitalLabel   matlab.ui.control.Label
        TimetoRecoverydaysLabel_3       matlab.ui.control.Label
        t_r_hosp                        matlab.ui.control.Slider
        WithAidofHospitalLabel          matlab.ui.control.Label
        ProbabilityoffatalityLabel      matlab.ui.control.Label
        P_d                             matlab.ui.control.Slider
        TimetoRecoverydaysLabel         matlab.ui.control.Label
        t_r                             matlab.ui.control.Slider
        MeanNumberofSocialContactsLabel  matlab.ui.control.Label
        MeanNumberofSocialContactsLabel_2  matlab.ui.control.Label
        SevereSymptomsnotreatmentLabel  matlab.ui.control.Label
        per10kpeopleLabel               matlab.ui.control.Label
        TimetobecomeinfectiousdaysLabel  matlab.ui.control.Label
        t_i                             matlab.ui.control.Slider
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        DATA % Description
        DATA_Deaths
        Countries
        States
        inf_vs_t
        dth_vs_t
        Lat
        Long
        dates
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../COVID-19/csse_covid_19_data/csse_covid_19_time_series/')
            
            try
                Y = importdata('time_series_19-covid-Confirmed.csv');
            catch
                [~, pathname, ~] = uigetfile('time_series_19-covid-Confirmed.csv','Navigate to find the File time_series_19-covid-Confirmed.csv');
                addpath(pathname);
                Y = importdata('time_series_19-covid-Confirmed.csv');
            end
            
            app.dates = Y.textdata(1,5:end);
            app.DATA = Y.data(:,3:end);
            app.Countries = Y.textdata(2:end,2);
            app.States = Y.textdata(2:end,1);
            app.Lat = Y.data(:,1);
            app.Long = Y.data(:,2);
            
            Y = importdata('time_series_19-covid-Deaths.csv');
            app.DATA_Deaths = Y.data(:,3:end);
            
            app.countries.Items = unique(app.Countries);
            app.countries.Value = 'US';
            
            placeSelectionChanged(app)
            
        end

        % Selection changed function: place
        function placeSelectionChanged(app, event)
            if app.specific.Value
                app.countries.Items = unique(app.Countries);
                app.countries.Value = 'US';
            end
            
            Make_Plot(app)
        end

        % Callback function: PlotTypesButtonGroup, days_for_pred, 
        % days_for_trend
        function PlotTypeButtonGroupSelectionChanged(app, event)
            Make_Plot(app)
        end

        % Value changed function: countries
        function countriesValueChanged(app, event)
            Make_Plot(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            Make_Growth_Rate_Table(app)
        end

        % Callback function: ShowMapButton, TimeSlider
        function ShowMapButtonPushed(app, event)
            Make_Map(app);
            
        end

        % Button pushed function: GenerateMovieButton
        function GenerateMovieButtonPushed(app, event)
            Make_Movie(app)           
        end

        % Callback function: M_Hosp, P_d, RunSimmulationButton, 
        % T_vis, d_post, d_pre, t_i, t_r, t_r_hosp, t_r_nohosp
        function RunSimmulationButtonPushed(app, event)
            Run_SSA_Sim(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.place = uibuttongroup(app.StatisticsTab);
            app.place.SelectionChangedFcn = createCallbackFcn(app, @placeSelectionChanged, true);
            app.place.Title = 'All Countries or Selected Subset of Countries';
            app.all = uitogglebutton(app.place);
            app.all.Text = 'All Countries';
            app.all.Value = true;
            app.specific = uitogglebutton(app.place);
            app.specific.Text = 'Specific Countires';
            app.TabGroup_2 = uitabgroup(app.StatisticsTab);
            app.InfectionsTab = uitab(app.TabGroup_2);
            app.InfectionsTab.Title = 'Infections';
            app.ax_infections = uiaxes(app.InfectionsTab);
            title(app.ax_infections, 'Infections over time')
            xlabel(app.ax_infections, 'Time (days)')
            ylabel(app.ax_infections, 'Infections')
            app.DeathsTab = uitab(app.TabGroup_2);
            app.DeathsTab.Title = 'Deaths';
            app.ax_deaths = uiaxes(app.DeathsTab);
            title(app.ax_deaths, 'Deaths over time')
            xlabel(app.ax_deaths, 'Time (days)')
            ylabel(app.ax_deaths, 'Deaths')
            app.GrowthRatebyCountryTab = uitab(app.TabGroup_2);
            app.GrowthRatebyCountryTab.Title = 'Growth Rate by Country';
            app.UpdateButton = uibutton(app.GrowthRatebyCountryTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.gr_table = uitable(app.GrowthRatebyCountryTab);
            app.gr_table.ColumnName = {'Country'; 'Doubling Time (days)'};
            app.gr_table.RowName = {};
            app.gr_hist = uiaxes(app.GrowthRatebyCountryTab);
            title(app.gr_hist, 'Histogram')
            xlabel(app.gr_hist, 'Doubling Time (days)')
            ylabel(app.gr_hist, 'Probability Density')
            app.PlotTypesButtonGroup = uibuttongroup(app.StatisticsTab);
            app.PlotTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypesButtonGroup.Title = 'Plot Types:';
            app.lin = uitogglebutton(app.PlotTypesButtonGroup);
            app.lin.Text = 'Linear';
            app.log = uitogglebutton(app.PlotTypesButtonGroup);
            app.log.Text = 'Logarithmic';
            app.log.Value = true;
            app.add_trend_lines = uicheckbox(app.StatisticsTab);
            app.add_trend_lines.Text = 'Include Trend lines';
            app.add_trend_lines.Value = true;
            app.daystocomputetrendLabel = uilabel(app.StatisticsTab);
            app.daystocomputetrendLabel.Text = 'days to compute trend';
            app.SubsetofCountriesListBoxLabel = uilabel(app.StatisticsTab);
            app.SubsetofCountriesListBoxLabel.Text = 'Subset of Countries';
            app.countries = uilistbox(app.StatisticsTab);
            app.countries.Multiselect = 'on';
            app.countries.ValueChangedFcn = createCallbackFcn(app, @countriesValueChanged, true);
            app.countries.Value = {'Item 1'};
            app.UseLabel = uilabel(app.StatisticsTab);
            app.UseLabel.Text = 'Use';
            app.days_for_trend = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_trend.Limits = [3 20];
            app.days_for_trend.RoundFractionalValues = 'on';
            app.days_for_trend.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_trend.Value = 10;
            app.mostrecentdaystovalidatepredictionsLabel = uilabel(app.StatisticsTab);
            app.mostrecentdaystovalidatepredictionsLabel.Text = 'most recent days to validate predictions';
            app.HoldoutLabel = uilabel(app.StatisticsTab);
            app.HoldoutLabel.Text = 'Hold out';
            app.days_for_pred = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_pred.Limits = [0 10];
            app.days_for_pred.RoundFractionalValues = 'on';
            app.days_for_pred.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_pred.Value = 3;
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps';
            app.map = uiaxes(app.MapsTab);
            title(app.map, 'Map of Pandemic')
            xlabel(app.map, '')
            ylabel(app.map, '')
            app.map.XTick = [];
            app.map.YTick = [];
            app.ShowMapButton = uibutton(app.MapsTab, 'push');
            app.ShowMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.ShowMapButton.Text = 'Show Map';
            app.TimeSliderLabel = uilabel(app.MapsTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.MapsTab);
            app.TimeSlider.Limits = [-40 0];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.TimerelativetotodaydaysLabel = uilabel(app.MapsTab);
            app.TimerelativetotodaydaysLabel.Text = 'Time (days) relative to today';
            app.GenerateMovieButton = uibutton(app.MapsTab, 'push');
            app.GenerateMovieButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMovieButtonPushed, true);
            app.GenerateMovieButton.Text = 'Generate Movie';
            app.SIRSimulationsTab = uitab(app.TabGroup);
            app.SIRSimulationsTab.Title = 'SIR Simulations';
            app.SocialDistancingPreSymptomSliderLabel = uilabel(app.SIRSimulationsTab);
            app.SocialDistancingPreSymptomSliderLabel.Text = 'Social Distancing Pre-Symptom';
            app.d_pre = uislider(app.SIRSimulationsTab);
            app.d_pre.Limits = [0 50];
            app.d_pre.MajorTicks = [0 10 20 30 40 50];
            app.d_pre.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.d_pre.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.d_pre.Value = 20;
            app.AvailableHospitalBedsLabel = uilabel(app.SIRSimulationsTab);
            app.AvailableHospitalBedsLabel.Text = 'Available Hospital Beds';
            app.M_Hosp = uislider(app.SIRSimulationsTab);
            app.M_Hosp.Limits = [0 1000];
            app.M_Hosp.MajorTicks = [0 200 400 600 800 1000];
            app.M_Hosp.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.M_Hosp.Value = 200;
            app.TimetoVisibleSymptomsdaysSliderLabel = uilabel(app.SIRSimulationsTab);
            app.TimetoVisibleSymptomsdaysSliderLabel.Text = 'Time to Visible Symptoms (days)';
            app.T_vis = uislider(app.SIRSimulationsTab);
            app.T_vis.Limits = [0 50];
            app.T_vis.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.T_vis.Value = 14;
            app.SocialDistancingPostSymptomSliderLabel = uilabel(app.SIRSimulationsTab);
            app.SocialDistancingPostSymptomSliderLabel.Text = 'Social Distancing Post-Symptom';
            app.d_post = uislider(app.SIRSimulationsTab);
            app.d_post.Limits = [0 50];
            app.d_post.MajorTicks = [0 10 20 30 40 50];
            app.d_post.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.d_post.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.d_post.Value = 2;
            app.TimetoRecoverydaysLabel = uilabel(app.SIRSimulationsTab);
            app.TimetoRecoverydaysLabel.Text = 'Time to Recovery (days)';
            app.t_r = uislider(app.SIRSimulationsTab);
            app.t_r.Limits = [0 50];
            app.t_r.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_r.Value = 14;
            app.ssa_results = uiaxes(app.SIRSimulationsTab);
            title(app.ssa_results, 'Title')
            xlabel(app.ssa_results, 'time (days)')
            ylabel(app.ssa_results, 'fraction of population')
            app.InitialSusceptibleEditFieldLabel = uilabel(app.SIRSimulationsTab);
            app.InitialSusceptibleEditFieldLabel.Text = 'Initial Susceptible';
            app.S_0 = uieditfield(app.SIRSimulationsTab, 'numeric');
            app.S_0.Value = 100000;
            app.InitialInfectedUndetectedEditFieldLabel = uilabel(app.SIRSimulationsTab);
            app.InitialInfectedUndetectedEditFieldLabel.Text = 'Initial Infected (Undetected)';
            app.IU_0 = uieditfield(app.SIRSimulationsTab, 'numeric');
            app.IU_0.Value = 10;
            app.InitialInfectedDetectedEditFieldLabel = uilabel(app.SIRSimulationsTab);
            app.InitialInfectedDetectedEditFieldLabel.Text = 'Initial Infected (Detected)';
            app.ID_0 = uieditfield(app.SIRSimulationsTab, 'numeric');
            app.RunSimmulationButton = uibutton(app.SIRSimulationsTab, 'push');
            app.RunSimmulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.RunSimmulationButton.Text = 'Run Simmulation';
            app.MildNoSymptomsLabel = uilabel(app.SIRSimulationsTab);
            app.MildNoSymptomsLabel.Text = '(Mild/No Symptoms)';
            app.TimetoRecoverydaysLabel_2 = uilabel(app.SIRSimulationsTab);
            app.TimetoRecoverydaysLabel_2.Text = 'Time to Recovery (days)';
            app.t_r_nohosp = uislider(app.SIRSimulationsTab);
            app.t_r_nohosp.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_r_nohosp.Value = 70;
            app.SevereSymptomsNoHospitalLabel = uilabel(app.SIRSimulationsTab);
            app.SevereSymptomsNoHospitalLabel.Text = '(Severe Symptoms, No Hospital)';
            app.TimetoRecoverydaysLabel_3 = uilabel(app.SIRSimulationsTab);
            app.TimetoRecoverydaysLabel_3.Text = 'Time to Recovery (days)';
            app.t_r_hosp = uislider(app.SIRSimulationsTab);
            app.t_r_hosp.Limits = [0 50];
            app.t_r_hosp.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_r_hosp.Value = 14;
            app.WithAidofHospitalLabel = uilabel(app.SIRSimulationsTab);
            app.WithAidofHospitalLabel.Text = '(With Aid of Hospital)';
            app.ProbabilityoffatalityLabel = uilabel(app.SIRSimulationsTab);
            app.ProbabilityoffatalityLabel.Text = 'Probability of fatality';
            app.P_d = uislider(app.SIRSimulationsTab);
            app.P_d.Limits = [0 0.2];
            app.P_d.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.P_d.Value = 0.1;
            app.MeanNumberofSocialContactsLabel = uilabel(app.SIRSimulationsTab);
            app.MeanNumberofSocialContactsLabel.Text = '(Mean Number of Social Contacts)';
            app.MeanNumberofSocialContactsLabel_2 = uilabel(app.SIRSimulationsTab);
            app.MeanNumberofSocialContactsLabel_2.Text = '(Mean Number of Social Contacts)';
            app.SevereSymptomsnotreatmentLabel = uilabel(app.SIRSimulationsTab);
            app.SevereSymptomsnotreatmentLabel.Text = '(Severe Symptoms, no treatment)';
            app.per10kpeopleLabel = uilabel(app.SIRSimulationsTab);
            app.per10kpeopleLabel.Text = '(per 10k people)';
            app.TimetobecomeinfectiousdaysLabel = uilabel(app.SIRSimulationsTab);
            app.TimetobecomeinfectiousdaysLabel.Text = 'Time to become infectious (days)';
            app.t_i = uislider(app.SIRSimulationsTab);
            app.t_i.Limits = [0 10];
            app.t_i.ValueChangedFcn = createCallbackFcn(app, @RunSimmulationButtonPushed, true);
            app.t_i.Value = 2;
            app.TextArea = uitextarea(app.SIRSimulationsTab);
            app.TextArea.Value = {'The following allows for a simple infection model with reactions:'; ''; 'Susceptible  ->  Infected (rates tuned by increasing social distancing)'; 'Infected -> Infectous (rate tuned by time to become infectious)'; 'Infectous  -> Symptomatic (rate tuned by ''Time to Visible Symptoms'')'; 'Infectious -> Recovered (rate tuned by ''time to recovery, mild symptoms'')'; 'Symptomatic -> Hospitalized (rate tuned bynumber of open hospital beds)'; 'Symptomatic -> Recovered or Deceased (rates tuned by ''time to recovery, severe symptoms'' and ''Probability of Fatality'')'; 'Hospitalized -> Recovered or Deceased (rates tuned by ''time to recovery, with aid of hospital'' and ''Probability of Fatality'')'; ''; 'Default parameters are arbitrary. Suggestions for parameters and modification of events is very welcome.'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Matlab_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end